classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Plot3DMenu                      matlab.ui.container.Menu
        ImageToolsMenu                  matlab.ui.container.Menu
        ImageTypeMenu                   matlab.ui.container.Menu
        TSPMenu                         matlab.ui.container.Menu
        IRMenu                          matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        MarkerMatchingMenu              matlab.ui.container.Menu
        EnableMenu                      matlab.ui.container.Menu
        DisableMenu                     matlab.ui.container.Menu
        AdvanceOptionsMenu              matlab.ui.container.Menu
        NoiseReductionMenu              matlab.ui.container.Menu
        EnableMenu_2                    matlab.ui.container.Menu
        DisableMenu_2                   matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        StoreButton                     matlab.ui.control.Button
        ImageIndexEditField             matlab.ui.control.NumericEditField
        ImageIndexEditFieldLabel        matlab.ui.control.Label
        ShowImageButton                 matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        ImageFilesLabel                 matlab.ui.control.Label
        ImportSectionPanel              matlab.ui.container.Panel
        ImagePreviewCheckBox            matlab.ui.control.CheckBox
        DirectImportCheckBox            matlab.ui.control.CheckBox
        NoofImagesEditField             matlab.ui.control.NumericEditField
        NoofImagesEditFieldLabel        matlab.ui.control.Label
        StartingIndexEditField          matlab.ui.control.NumericEditField
        StartingIndexEditFieldLabel     matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        showButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ToolsPanel                      matlab.ui.container.Panel
        SelectImageEditField_2          matlab.ui.control.NumericEditField
        SelectImageEditField_2Label     matlab.ui.control.Label
        CropdimensionEditField          matlab.ui.control.EditField
        CropdimensionEditFieldLabel     matlab.ui.control.Label
        ShowButton                      matlab.ui.control.Button
        ApplyButton_4                   matlab.ui.control.Button
        CropButton                      matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        Flip180Button                   matlab.ui.control.Button
        Rotate90Button                  matlab.ui.control.Button
        xButton                         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TSPProcessingBlockTab           matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        CurrentImageTextArea            matlab.ui.control.TextArea
        CurrentImageTextAreaLabel       matlab.ui.control.Label
        SetButton_2                     matlab.ui.control.Button
        SelectLabel_2                   matlab.ui.control.Label
        SetcurrentprocessingTSPimageEditField  matlab.ui.control.NumericEditField
        SetcurrentprocessingTSPimageEditFieldLabel  matlab.ui.control.Label
        ApplyFilterPanel                matlab.ui.container.Panel
        ToolButton                      matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        ProcessShowButton_3             matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AverageButton                   matlab.ui.control.RadioButton
        MedianButton                    matlab.ui.control.RadioButton
        AverageEditField                matlab.ui.control.EditField
        AverageEditFieldLabel           matlab.ui.control.Label
        MedianEditField                 matlab.ui.control.EditField
        MedianEditFieldLabel            matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        ReduceNoisePanel                matlab.ui.container.Panel
        DisplayButton                   matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        SectionCordinatesEditField_2    matlab.ui.control.EditField
        SectionCordinatesEditField_2Label  matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        SizeofFilterElementEditField    matlab.ui.control.EditField
        SizeofFilterElementEditFieldLabel  matlab.ui.control.Label
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        SectionButton_3                 matlab.ui.control.RadioButton
        SegmentButton                   matlab.ui.control.RadioButton
        NormalButton                    matlab.ui.control.RadioButton
        SegmentCordinatesEditField      matlab.ui.control.EditField
        SegmentCordinatesEditFieldLabel  matlab.ui.control.Label
        ApplyButton_3                   matlab.ui.control.Button
        ProcessShowButton_2             matlab.ui.control.Button
        DropDown_3                      matlab.ui.control.DropDown
        DetectEdgePanel                 matlab.ui.container.Panel
        GetButton                       matlab.ui.control.Button
        ConvertToBinaryPanel            matlab.ui.container.Panel
        SectionCordinatesEditField      matlab.ui.control.EditField
        SectionCordinatesEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        SectionButton_2                 matlab.ui.control.RadioButton
        NormalButton_2                  matlab.ui.control.RadioButton
        ApplyButton_2                   matlab.ui.control.Button
        ProcessShowButton               matlab.ui.control.Button
        CutoffvalueSpinner              matlab.ui.control.Spinner
        CutoffvalueSpinnerLabel         matlab.ui.control.Label
        InvertButton                    matlab.ui.control.StateButton
        Tab                             matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        SetButton                       matlab.ui.control.Button
        SelectLabel                     matlab.ui.control.Label
        SetcurrentprocessingIRimageEditField  matlab.ui.control.NumericEditField
        SetcurrentprocessingIRimageEditFieldLabel  matlab.ui.control.Label
        CombineEdgesPanel               matlab.ui.container.Panel
        AddButton                       matlab.ui.control.Button
        SelectImageIndicesEditField     matlab.ui.control.EditField
        SelectImageIndicesEditFieldLabel  matlab.ui.control.Label
        DetectCompleteEdgePanel         matlab.ui.container.Panel
        GetEdgeButton_2                 matlab.ui.control.Button
        NormalTransitionEdgePanel       matlab.ui.container.Panel
        ProcessApplyButton              matlab.ui.control.Button
        SecondaryEdgeoptimizerSpinner   matlab.ui.control.Spinner
        SecondaryEdgeoptimizerSpinnerLabel  matlab.ui.control.Label
        PrimaryedgeoptimizerSpinner     matlab.ui.control.Spinner
        PrimaryedgeoptimizerLabel       matlab.ui.control.Label
        Tab_2                           matlab.ui.container.Tab
        Plot3DPanel                     matlab.ui.container.Panel
        XButton                         matlab.ui.control.Button
        SelectColorScaleDropDown        matlab.ui.control.DropDown
        SelectColorScaleDropDownLabel   matlab.ui.control.Label
        SelectImageEditField            matlab.ui.control.NumericEditField
        SelectImageEditFieldLabel       matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        AspectRatioAREditField          matlab.ui.control.NumericEditField
        AspectRatioAREditFieldLabel     matlab.ui.control.Label
        TrailingEdgeofsectionEditField  matlab.ui.control.NumericEditField
        TrailingEdgeofsectionEditFieldLabel  matlab.ui.control.Label
        LeadingEdgeofSectionEditField   matlab.ui.control.NumericEditField
        LeadingEdgeofSectionEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        flag_import_IR = 0;
        flag_TSPMenu = 1;
        flag_markermatching_enable = 0;
        flag_noisereduction_enable = 0;
        flag_adv_opt = 1;
        flag_IRMenu = 0;
        flag_add = 0;
        flag_cpt_edge = 0;
        flag_norm_edge = 0;
        add_values % values of text edit field for adding two images
        invert = false;
        count = 0; % image count
        IR_imp_count = 0; % 'import' count
        current_IR_img
        IR_imp_idx = zeros(1,20); % 'import' index array for IR images
        Image_Cell cell % store all useful images
        Norm_Edge % normal edge of ir image
        Cpt_Edge % complete edge of ir image
        add_Edge
        show_image
        Intensity_Crop
        current_TSP_img
        avg_TSP_img
        med_TSP_img
        TSPPro_img
        bin_TSP_img
        edge_TSP_img
        run         % run image tsp
        ref         % reference image tsp
        drk         % dark image tsp
        TSP_imp_count = 0;
        TSP_imp_idx = 0;
        TSP_file_count = 0;
        current_crop_img % current crop image
        crop_img
        img_90 
        img_180
        flag_import_TSP = 0;
        avg_flag = 0;
        med_flag = 0;
        flag_Pro = 0;
        flag_bin = 0;
        flag_filter = 0;
        flag_binary = 0;
        flag_noise = 0;
        flag_get = 0;
        flag_tools = 0;
        flag_crop = 0;
        flag_90 = 0;
        flag_180 = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %value = app.DropDown.Value;
            chkbox = app.DirectImportCheckBox.Value;
            preview_chkbox = app.ImagePreviewCheckBox.Value;
            if app.flag_IRMenu == 1 
                name = '';
                [name,path] = uigetfile();
                filename = [path name];
                if name ~= 0
                    app.count = app.count + 1;
                    app.IR_imp_count = app.IR_imp_count + 1;
                    app.IR_imp_idx(app.IR_imp_count) = app.count;
                    I = importdata(filename);
                    Num = num2str(app.count);
                    add_name = [Num '.   ' name];
                    app.TextArea.Value{app.count} = add_name;
                    app.Image_Cell{app.count} = (I);
                    app.current_IR_img = (I);
                    imshow(app.Image_Cell{app.count},'Parent',app.UIAxes)
                    app.flag_import_IR = 1;
                    app.flag_import_TSP = 0;
                end
            end
            if app.flag_TSPMenu == 1 && chkbox == 0
                Value = app.DropDown_2.Value;

                    if strcmp(Value,"Run Image") == 1
                        Start = app.StartingIndexEditField.Value;
                        total = app.NoofImagesEditField.Value;
                        folder = uigetdir();
                        if ~isequal(folder,0)
                            i=Start;
                            str = [folder '\i' num2str(i) '.tif'];  % avarage of run avarage
                            I = imread(str);                        
                            [m,n] = size(I);                        
                            Run = zeros(m,n);                        
                            for i=1:total
                                j = i+Start-1;
                                str = [folder '\i',num2str(j),'.tif'];
                                I = im2double(imread(str));                            
                                Run = Run+I;
                            end
                            Run = Run/total;
                            app.run = Run;
                            app.TSP_file_count = app.TSP_file_count +1;
                        end
                        
                    elseif strcmp(Value,"Reference Image") == 1
                        Start = app.StartingIndexEditField.Value;
                        total = app.NoofImagesEditField.Value;
                        folder = uigetdir();
                        if ~isequal(folder,0)
                            i=Start;
                            str = [folder '\i',num2str(i),'.tif'];  % avarage of reference image
                            I = im2double(imread(str));
                            [m,n] = size(I);
                            Ref = zeros(m,n);
                            for i=1:total
                                j = i+Start-1;
                                str = [folder '\i',num2str(j),'.tif'];
                                I = im2double(imread(str));
                                Ref = Ref+I;
                            end
                            Ref = Ref/total;
                            app.ref = Ref;
                            app.TSP_file_count = app.TSP_file_count +1;
                            if preview_chkbox == 1
                                max_ref = max(Ref,[],"all");
                                ref_image = Ref/max_ref;
                                imtool(ref_image)
                            end
                        end

                    elseif strcmp(Value,"Dark Image") == 1
                        Start = app.StartingIndexEditField.Value;
                        total = app.NoofImagesEditField.Value;
                        folder = uigetdir();
                        if ~isequal(folder,0)
                            i=Start;
                            str = [folder '\i',num2str(i),'.tif'];  %  avarage of dark images
                            I = imread(str);
                            [m,n] = size(I);
                            dark = zeros(m,n);
                            for i=1:total
                                j = i+Start-1;
                                str = [folder '\i',num2str(j),'.tif'];
                                I = im2double(imread(str));
                                dark = dark+I;
                            end
                            dark = dark/total;
                            app.drk = dark;
                            app.TSP_file_count = app.TSP_file_count +1;
                        end
                    end
                    if app.TSP_file_count == 3 
                        app.TSP_file_count = 0;
                        ref_new = app.ref - app.drk;                                  % Ratio of wind on and wind off
                        run_new = app.run - app.drk; %******************
                        if app.flag_adv_opt == 1
                            promt = {'Enter Marker Location (X Value)','Enter Marker Location (Y Value)'};
                            dlgtitle = 'Input';
                            answer = inputdlg(promt,dlgtitle,[1 100;1 100],{'0' '0'},"on");
                            if isempty(answer)
                                x_value = [];
                                y_value = [];
                            else
                                x_value = str2num(answer{1});
                                y_value = str2num(answer{2});
                            end
                                
                            
                            [~,Sz] = size(y_value);
                            markers_given = zeros(2,Sz);
                            markers_given(1,:) = x_value;
                            markers_given(2,:) = y_value;
                        end
                        if app.flag_adv_opt == 1 && Sz ~= 0
                            max_a = max(run_new,[],"all");
                            A = run_new/max_a;
                            max_b = max(ref_new,[],"all");
                            B = ref_new/max_b;
                            [~,Count] = size(markers_given);
                            for S = 1:2
                                if S == 1
                                    IMG = B;
                                else
                                    IMG = A;
                                end
                                [Row,Col] = size(IMG);
                                bin_IMG = ones(Row,Col);
                                for i = 1:Row
                                    for j = 1:Col
                                        if IMG(i,j) > 0.2 % constant ************************************************
                                            bin_IMG(i,j) = 0;
                                        end
                                    end
                                end
                                for i = 1:Count
                                    h = markers_given(1,i);
                                    k = markers_given(2,i);
                                    if bin_IMG(k,h) == 1
                                        bin_IMG(k,h) = -1;
                                    end
                                    for r= 1:100
                                        Array1 = 1:r;
                                        Array2 = sort(Array1,'descend');
                                        y = [k-Array2,k,k+Array1];
                                        x = [h-Array2,h,h+Array1];

                                        [~,s] = size(y);
                                        for l = 1:s
                                            if y(l)<1
                                                y(l) = 1;
                                            end
                                            if y(l)> Row
                                                y(l) = Row;
                                            end
                                            if x(l)<1
                                                x(l) = 1;
                                            end
                                            if x(l)> Col
                                                x(l) = Col;
                                            end
                                        end
                                        flag_1 = 0;
                                        for a= 1:s      % a --> Row
                                            for b= 1:s      % b --> Col  
                                                R = (y(a) - k)^2 +  (x(b) - h)^2;

                                                if R > (r-1)^2 && R <= r^2
                                                    X = x(b);
                                                    Y = y(a);
                                                    if bin_IMG(Y,X) == 1
                                                        bin_IMG(Y,X) = -1; % constant
                                                        flag_1 = 1;
                                                    end
                                                end
                                            end
                                        end
                                        if flag_1 ==0
                                            break;
                                        end
                                    end
                                end
                                if S == 1
                                    bin_ref = bin_IMG;
                                else
                                    bin_run = bin_IMG;
                                end
                            end
                        end

                        if app.flag_markermatching_enable == 1 || (app.flag_adv_opt == 1 && Sz ~= 0)

                            %Dictating the markers
                            if app.flag_adv_opt == 1
                                IMG = bin_ref;
                                var = -1;
                            else
                                IMG = ref_new;
                                var = 0;
                            end                          
                            [Row,Col] = size(IMG);
                            markers_0 = zeros(2,100);
                            Count = 0;
                            for j= 1:Row
                                for i= 1:Col
                                    val = IMG(j,i);
                                    if val == var
                                        Count = Count+1;
                                        markers_0(1,Count) = i; % 'i' is along x-axis {Columns}
                                        markers_0(2,Count) = j; % 'j' is along y-axis {Rows}
                                        h = i;
                                        k = j;
                                        for r= 1:100
                                            Array1 = 1:r;
                                            Array2 = sort(Array1,'descend');
                                            y = [k-Array2,k,k+Array1];
                                            x = [h-Array2,h,h+Array1];

                                            [~,s] = size(y);
                                            for l = 1:s
                                                if y(l)<1
                                                y(l) = 1;
                                                end
                                                if y(l)> Row
                                                    y(l) = Row;
                                                end
                                                if x(l)<1
                                                    x(l) = 1;
                                                end
                                                if x(l)> Col
                                                    x(l) = Col;
                                                end
                                            end
                                            flag_1 = 0;
                                            for a= 1:s      % a --> Row
                                                for b= 1:s      % b --> Col  
                                                    R = (y(a) - k)^2 +  (x(b) - h)^2;
                                                    if R > (r-1)^2 && R <= r^2
                                                        X = x(b);
                                                        Y = y(a);
                                                        if IMG(Y,X) == var
                                                            IMG(Y,X) = -1 + var; % constant
                                                            flag_1 = 1;
                                                        end
                                                    end
                                                end
                                            end
                                            if flag_1 ==0
                                                break;
                                            end
                                        end
                                    end
                                end
                            end
                            markers = zeros(2,Count);
                            for i = 1:Count
                                markers(1,i) = markers_0(1,i);
                                markers(2,i) = markers_0(2,i);
                            end
                            % Dictating the markers in secondary image
                            if app.flag_adv_opt == 1
                                Sec_IMG = bin_run;
                            else
                                Sec_IMG = run_new;
                            end                        
                            [Row,Col] = size(Sec_IMG);
                            Markers = zeros(2,Count,2);
                            Markers(:,:,1) = markers;
                            Count_sec = 0;
                            for i= 1:Count
                                h = markers(1,i); % column number
                                k = markers(2,i); % row number
                                flag_2 = 0;
                                for r= 1:100
                                    Array1 = 1:r;
                                    Array2 = sort(Array1,'descend');
                                    y = [k-Array2,k,k+Array1];
                                    x = [h-Array2,h,h+Array1];

                                    [~,s] = size(y);
                                    for l = 1:s
                                        if y(l)<1
                                            y(l) = 1;
                                        end
                                        if y(l)> Row
                                            y(l) = Row;
                                        end
                                        if x(l)<1
                                        x(l) = 1;
                                        end
                                        if x(l)> Col
                                            x(l) = Col;
                                        end
                                    end
                                    for a= 1:s      % a --> Row
                                        for b= 1:s      % b --> Col  
                                            R = (y(a) - k)^2 +  (x(b) - h)^2;
                                            if R > (r-1)^2 && R <= r^2
                                                X = x(b);
                                                Y = y(a);
                                                if Sec_IMG(Y,X) == var

                                                    Count_sec = Count_sec + 1;
                                                    Markers(1,Count_sec,2) = X;
                                                    Markers(2,Count_sec,2) = Y;
                                                    flag_2 = 1;
                                                end
                                            end
                                            if flag_2 ==1
                                                break;
                                            end
                                        end
                                        if flag_2 ==1
                                            break;
                                        end
                                    end
                                    if flag_2 ==1
                                        break;
                                    end
                                end
                            end
                            % Aligning both the images
                            disp(Markers)
                            x_diff_sum = 0;
                            y_diff_sum = 0;
                            for i = 1:Count
                                x_diff_sum = x_diff_sum + Markers(1,i,1) - Markers(1,i,2);
                                y_diff_sum = y_diff_sum + Markers(2,i,1) - Markers(2,i,2);
                            end
                            x_shift = round(x_diff_sum/Count);
                            y_shift = round(y_diff_sum/Count);
                            x_shift_abs = abs(x_shift);
                            y_shift_abs = abs(y_shift);
                            New_ref_image = zeros(Row + y_shift_abs,Col + x_shift_abs);
                            New_run_image = zeros(Row + y_shift_abs,Col + x_shift_abs);

                            if (x_shift >= 0) && (y_shift >= 0)
                                for i = 1:Row
                                    for j = 1:Col
                                        New_ref_image(i,j) = ref_new(i,j);
                                        New_run_image(i + y_shift_abs,j + x_shift_abs) = run_new(i,j);
                                    end
                                end
                            elseif (x_shift >= 0) && (y_shift <= 0)
                                for i = 1:Row
                                    for j = 1:Col
                                        New_ref_image(i + y_shift_abs,j) = ref_new(i,j);
                                        New_run_image(i,j + x_shift_abs) = run_new(i,j);
                                    end
                                end
                            elseif (x_shift <= 0) && (y_shift >= 0)
                                for i = 1:Row
                                    for j = 1:Col
                                        New_ref_image(i,j + x_shift_abs) = ref_new(i,j);
                                        New_run_image(i + y_shift_abs,j) = run_new(i,j);
                                    end
                                end
                            elseif (x_shift <= 0) && (y_shift <= 0)
                                for i = 1:Row
                                    for j = 1:Col
                                        New_ref_image(i + y_shift_abs,j + x_shift_abs) = ref_new(i,j);
                                        New_run_image(i,j) = run_new(i,j);
                                    end
                                end
                            end
                        end
                        if app.flag_markermatching_enable ==1
                            Intensity = (New_ref_image).*(1./New_run_image);
                        else
                            Intensity = (ref_new).*(1./run_new);
                        end
                        % I1 = run_new1.*(1./ref_new); %*****************                        
                        app.current_TSP_img = Intensity;
                        app.count = app.count + 1;
                        app.Image_Cell{app.count} = Intensity;
                        Num = num2str(app.count);
                        add_name = [Num '.   ' 'Intensity Image'];
                        app.TextArea.Value{app.count} = add_name;
                        app.TSP_imp_count = app.TSP_imp_count + 1;
                        app.TSP_imp_idx(app.TSP_imp_count) = app.count;
                        app.flag_import_TSP = 1;
                        app.flag_import_IR = 0;
                    end
            end
            if chkbox == 1
                name = '';
                [name,path] = uigetfile();
                filename = [path name];
                if name ~= 0
                    app.count = app.count + 1;
                    I = importdata(filename);
                    if isstruct(I)
                        img = I.cdata;
                        I = img;
                    end
                    [~,~,S] = size(I);
                    if S == 3
                        img = rgb2gray(I);
                        I = img;
                    end
                    if isa(I,"uint8") 
                        img = double(I)/255;
                        I = img;
                    end


                    Num = num2str(app.count);
                    add_name = [Num '.   ' name];
                    app.TextArea.Value{app.count} = add_name;
                    app.Image_Cell{app.count} = I;                   
                    app.current_TSP_img = I;
                    app.TSP_imp_count = app.TSP_imp_count + 1;
                    app.TSP_imp_idx(app.TSP_imp_count) = app.count;
                    app.flag_import_IR = 0;
                    app.flag_import_TSP = 1;
                end
            end

        end

        % Button pushed function: showButton
        function showButtonPushed(app, event)
            if app.flag_import_IR == 1
                idx = app.IR_imp_idx(app.IR_imp_count);
                IMG = (app.Image_Cell{idx});
                MIN = min(IMG,[],'all');
                MAX = max(IMG,[],'all');
                imshow(IMG,'Parent',app.UIAxes)
                colormap(app.UIAxes,"hot")
                clim(app.UIAxes,[MIN MAX])
            elseif app.flag_import_TSP == 1
                idx = app.TSP_imp_idx(app.TSP_imp_count);
                I = app.Image_Cell{idx};
                imshow(I,'Parent',app.UIAxes)
            end
            
        end

        % Button pushed function: ProcessShowButton
        function ProcessShowButtonPushed(app, event)
            Value = app.CutoffvalueSpinner.Value;
            method = app.ButtonGroup_2.SelectedObject;
            Cord = app.SectionCordinatesEditField.Value;
            cuttoff = Value/100;
            Cordinate = str2num(Cord);
            [~,siz] = size(Cordinate);
            [r,c] = size(app.current_TSP_img);
            I0 = app.current_TSP_img;
            flag_warn1 = 0;
            % flag_warn2 = 0;
            if siz ~= 4 && strcmp(method.Text,'Section')
                warndlg('Please enter the proper cordinates','Warning');
                flag_warn1 = 1;
                Xmin = 0;
                Ymin = 0;
                Width = 0;
                Height = 0;
            end
            if strcmp(method.Text,'Section') && flag_warn1 == 0
                Xmin = Cordinate(1);
                Ymin = Cordinate(2);
                Width = Cordinate(3) + Xmin;
                Height = Cordinate(4) + Ymin;
            end
            if strcmp(method.Text,'Normal')
                Xmin = 1;
                Ymin = 1;
                Width = c;
                Height = r;
            end
            if ((Width > c) || (Height > r)) && strcmp(method.Text,'Section')
                % flag_warn2 = 1;
                warndlg('Too large cordinates','Warning');
                Width = c;
                Height = r;
            end
            for i = Xmin:Width
                for j = Ymin:Height
                    I0(j,i) = 0;
                end
            end
            for i = Xmin:Width
                for j = Ymin:Height
                    if (app.current_TSP_img(j,i) > cuttoff ) && ~(app.invert)
                        I0(j,i) = 1;
                    elseif (app.current_TSP_img(j,i) < cuttoff ) && app.invert
                        I0(j,i) = 1;
                    end
                end
            end
            if flag_warn1 == 0
                app.bin_TSP_img = I0;
                imshow(I0,'Parent',app.UIAxes)
                app.flag_bin = 1;
            end
        end

        % Value changing function: CutoffvalueSpinner
        function CutoffvalueSpinnerValueChanging(app, event)
            changingValue = event.Value;
            method = app.ButtonGroup_2.SelectedObject;
            Cord = app.SectionCordinatesEditField.Value;
            cuttoff = changingValue/100;
            Cordinate = str2num(Cord);
            [~,siz] = size(Cordinate);
            [r,c] = size(app.current_TSP_img);
            I0 = app.current_TSP_img;
            flag_warn = 0;
            if siz ~= 4 && strcmp(method.Text,'Section')
                warndlg('Please enter the proper cordinates','Warning');
                flag_warn = 1;
                Xmin = 0;
                Ymin = 0;
                Width = 0;
                Height = 0;
            end
            if strcmp(method.Text,'Section') && flag_warn == 0
                Xmin = Cordinate(1);
                Ymin = Cordinate(2);
                Width = Cordinate(3) + Xmin;
                Height = Cordinate(4) + Ymin;
            elseif strcmp(method.Text,'Normal')
                Xmin = 1;
                Ymin = 1;
                Width = c;
                Height = r;
            end
            for i = Xmin:Width
                for j = Ymin:Height
                    I0(j,i) = 0;
                end
            end
            for i = Xmin:Width
                for j = Ymin:Height
                    if (app.current_TSP_img(j,i) > cuttoff ) && ~(app.invert)
                        I0(j,i) = 1;
                    elseif (app.current_TSP_img(j,i) < cuttoff ) && app.invert
                        I0(j,i) = 1;
                    end
                end
            end
            if flag_warn == 0
                app.bin_TSP_img = I0;
                imshow(I0,'Parent',app.UIAxes)
                app.flag_bin = 1;
            end
        end

        % Value changed function: InvertButton
        function InvertButtonValueChanged(app, event)
            value = app.InvertButton.Value;
            app.invert = value;
        end

        % Value changing function: SecondaryEdgeoptimizerSpinner
        function SecondaryEdgeoptimizerSpinnerValueChanging(app, event)
            changingValue = event.Value;
            d = changingValue;
            loc = app.PrimaryedgeoptimizerSpinner.Value;
            % idx = app.IR_imp_idx(app.imp_count);
            [r,c] = size(app.current_IR_img);
            Norm_edge = zeros(r,c);
            IMG = (app.current_IR_img);
            xpos = 1;
            for i = 1:r
                s_img = smooth(IMG(i,:));
                s_img2 = smooth(s_img);
                min_slope = 0;
                for j = loc:c-d
                    dif_Y = s_img2(j) - s_img2(j+d);
                    slope = dif_Y/d;
                    if slope < min_slope
                        min_slope = slope;
                        xpos = j;
                    end
                end
                Norm_edge(i,xpos) = 1;
            end
            app.Norm_Edge = Norm_edge;
            imshow(Norm_edge,'Parent',app.UIAxes)
        end

        % Button pushed function: ProcessApplyButton
        function ProcessApplyButtonPushed(app, event)
            d = app.SecondaryEdgeoptimizerSpinner.Value;
            loc = app.PrimaryedgeoptimizerSpinner.Value;
            % idx = app.IR_imp_idx(app.imp_count);
            [r,c] = size(app.current_IR_img);
            Norm_edge = zeros(r,c);
            IMG = (app.current_IR_img);
            xpos = 1;
            for i = 1:r
                s_img = smooth(IMG(i,:));
                s_img2 = smooth(s_img);
                min_slope = 0;
                for j = loc:c-d
                    dif_Y = s_img2(j) - s_img2(j+d);
                    slope = dif_Y/d;
                    if slope < min_slope
                        min_slope = slope;
                        xpos = j;
                    end
                end
                Norm_edge(i,xpos) = 1;
            end
            app.Norm_Edge = Norm_edge;
            imshow(Norm_edge,'Parent',app.UIAxes)
            app.flag_cpt_edge = 0;
            app.flag_add = 0;
            app.flag_norm_edge = 1;
        end

        % Button pushed function: GetEdgeButton_2
        function GetEdgeButton_2Pushed(app, event)
            % idx = app.IR_imp_idx(app.imp_count);
            [r,c] = size(app.current_IR_img);
            IMG = (app.current_IR_img);
            T0 = zeros(r,c);
            t0 = zeros(r,c);
            S0 = zeros(r,c);
            M0 = zeros(r,c);
            m0 = zeros(r,c);
            % Column-wise Edge extraction
            for i = 1:r-5
                j = i + 5;
                T0(r+1 - i,:) = IMG(r+1 - i,:) - IMG(r+1 - j,:);
            end
            for i = 1:r-5
                j = i + 5;
                M0(j,:) = IMG(i,:) - IMG(j,:);
            end
            for i = 1:c
                t0(:,i) = T0(:,i)>=0.01;
            end
            for i = 1:c
                m0(:,i) = M0(:,i)>=0.01;
            end
            S = m0 + t0;
            for i = 1:r-1
                j = i + 1;
                S0(j,:) = (S(j,:) - S(i,:)); 
            end
            app.Cpt_Edge = S0 + app.Norm_Edge;
            imshow(app.Cpt_Edge,'Parent',app.UIAxes)
            app.flag_cpt_edge = 1;
            app.flag_add = 0;
            app.flag_norm_edge = 0;
        end

        % Button pushed function: StoreButton
        function StoreButtonPushed(app, event)
            Name = app.NameEditField.Value;
            flag_warn = 0;
            if isempty(Name)
                warndlg('Please enter the Name','Warning');
                flag_warn = 1;
            end
            if app.flag_cpt_edge == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.Cpt_Edge;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            elseif app.flag_add == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.add_Edge;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            elseif app.flag_norm_edge == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.Norm_Edge;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            elseif app.flag_filter == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.current_TSP_img;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            elseif app.flag_binary == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.current_TSP_img;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            elseif app.flag_noise == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.current_TSP_img;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            elseif app.flag_get == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.edge_TSP_img;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            elseif app.flag_tools == 1 && flag_warn == 0
                app.count = app.count + 1;
                Num = num2str(app.count);
                app.Image_Cell{app.count} = app.current_crop_img;
                new_name = [Num '.   ' Name];
                app.TextArea.Value{app.count} = new_name;
            end
        end

        % Value changed function: SelectImageIndicesEditField
        function SelectImageIndicesEditFieldValueChanged(app, event)
            value = app.SelectImageIndicesEditField.Value;
            app.add_values = str2num(value);
            if isempty(app.add_values)  
                app.flag_add = 0;
            else
                app.flag_add = 1;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            [~,siz] = size(app.add_values);
            if siz >= 2
               [r,c] = size(app.Image_Cell{app.add_values(1)});
               added_image = zeros(r,c);
                for i=1:siz
                    idx = app.add_values(i);
                    added_image = added_image + app.Image_Cell{idx};
                end
                imshow(added_image,'Parent',app.UIAxes)
                app.add_Edge = added_image;
                app.flag_add = 1;
                app.flag_cpt_edge = 0;
                app.flag_norm_edge = 0;
            end
            
        end

        % Button pushed function: ShowImageButton
        function ShowImageButtonPushed(app, event)
            idx = app.ImageIndexEditField.Value;
            if idx <= app.count && idx ~=0
                img = app.Image_Cell{idx};
                imshow(img,'Parent',app.UIAxes)
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            LES = app.LeadingEdgeofSectionEditField.Value;
            TES = app.TrailingEdgeofsectionEditField.Value;
            AR = app.AspectRatioAREditField.Value;
            idx = app.SelectImageEditField.Value;
            IMG = app.Image_Cell{idx};
            value = app.SelectColorScaleDropDown.Value;
            Cmap = colormap(app.UIAxes,value);
            MIN = min(IMG,[],'all');
            MAX = max(IMG,[],'all');
            [R,C] = size(IMG);
            IMGD = zeros(R,C);
            dif = (MAX -MIN)/254;
            for i = 1:R
                for j = 1:C
                    if IMG(i,j) == MIN
                        IMGD(i,j) = 0;
                    elseif IMG(i,j) >= MAX
                        IMGD(i,j) = 255;
                    end
                    for k = 1:254
                        if  (MIN+(k*dif -dif)<IMG(i,j)) && (IMG(i,j)<=MIN+(k*dif))
                            IMGD(i,j) = k;
                        end
                    end
                end
            end
            IMGU = uint8(IMGD);
            IMGC = ind2rgb(IMGU,Cmap);

            %% Initializing variables
            x0 = linspace(0,LES,50);
            x1 = linspace(LES,TES,C);
            x2 = linspace(TES,1,100);
            x3 = linspace(0,1,300);
            y = linspace(0,AR,R);
            z0 = zeros(1,50);
            z1 = zeros(1,C);
            z2 = zeros(1,100);
            z3 = zeros(1,300);
            %% storing the hight values according to the X values along axis of cord length 
            
            for k=1:(450+C)
                if k <=50
                    c = x0(k);
                    z0(k) = 0.75*(0.2969*(sqrt(c)) - 0.126*c - 0.3516*c^2 + 0.2843*c^3 - 0.1015*c^4);
                elseif k <=50+C
                    c = x1(k-50);
                    z1(k-50) = 0.75*(0.2969*(sqrt(c)) - 0.126*c - 0.3516*c^2 + 0.2843*c^3 - 0.1015*c^4);
                elseif k <=150+C
                    c = x2(k-(50+C));
                    z2(k-(50+C)) = 0.75*(0.2969*(sqrt(c)) - 0.126*c - 0.3516*c^2 + 0.2843*c^3 - 0.1015*c^4);
                else
                    c = x3(k-(150+C));
                    z3(k-(150+C)) = -(0.75*(0.2969*(sqrt(c)) - 0.126*c - 0.3516*c^2 + 0.2843*c^3 - 0.1015*c^4));
                end
            end
            
            %%  making mesh of X, Y and Z values
            
            [Z0,~] = meshgrid(z0,y);
            Z1_mg = [z1;z1];
            [Z2,~] = meshgrid(z2,y);
            [Z3,~] = meshgrid(z3,y);
            [X0,Y0] = meshgrid(x0,y);
            [X1_mg,Y1_mg] = meshgrid(x1,y);
            [X2,Y2] = meshgrid(x2,y);
            [X3,Y3] = meshgrid(x3,y);
            
            
            %%  plotting the given image of required dimension on a 3D surface
            realcolour = ones(2,C,3);
            for j = 1:(R-1)
                Y1 = Y1_mg(j:j+1,:);
                X1 = X1_mg(1:2,:);
                Z1 = Z1_mg(1:2,:);
                coimg_R = IMGC(j,:,1);
                coimg_G = IMGC(j,:,2);
                coimg_B = IMGC(j,:,3);
                % clrmap = [coimg_R' coimg_G' coimg_B'];
                S = surf(Z1,Y1,X1);
                realcolour(:,:,1) = [coimg_R;coimg_R];
                realcolour(:,:,2) = [coimg_G;coimg_G];
                realcolour(:,:,3) = [coimg_B;coimg_B];
                %freezeColors;
                set(S,'CData',realcolour);
                hold("on")
            end
            
            %% plotting other parts of the model to make complete 3D surface
            surf(Z0,Y0,X0)
            surf(Z2,Y2,X2)
            surf(Z3,Y3,X3)
            colormap ([0 0 0])
            hold ("off")
            shading("interp")
            axis ("equal")
            xlabel("Z")
            ylabel("Y/S")
            zlabel("X/C")
            %xticks([1 290 C])
            xticklabels({'0.12','0.41','0.7'})
            %yticks([1 240 R])
            yticklabels({'0.16','0.5','0.85'})
            imshow(IMG,'Parent',app.UIAxes)
            

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            idx = app.SetcurrentprocessingIRimageEditField.Value;
            app.current_IR_img = app.Image_Cell{idx};
            imshow(app.Image_Cell{idx},'Parent',app.UIAxes2)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.avg_flag == 1
                app.current_TSP_img = app.avg_TSP_img;
                app.flag_filter = 1;
                app.flag_binary = 0;
                app.flag_noise = 0;
                app.flag_get = 0;
            elseif app.med_flag == 1
                app.current_TSP_img = app.med_TSP_img;
                app.flag_filter = 1;
                app.flag_binary = 0;
                app.flag_noise = 0;
                app.flag_get = 0;
            end

        end

        % Button pushed function: ProcessShowButton_3
        function ProcessShowButton_3Pushed(app, event)
            button = app.ButtonGroup.SelectedObject.Text;
            median_value = app.MedianEditField.Value;
            Value_med = str2num(median_value);
            Value_avg = str2double(app.AverageEditField.Value);
            [~,mid_siz] = size(Value_med);
            if mid_siz ~=2 || Value_med(1) == 0 || Value_med(2) == 0
                Value_med(1) = 5;
                Value_med(2) = 5;
            end
            if Value_avg == 0
                Value_avg = 1;
            end
            app.Lamp_2.Color = [1.00 0.41 0.16];
            if strcmp(button,'Average') == 1
                IMG = app.current_TSP_img;
                h = fspecial("average",Value_avg);
                I = imfilter(IMG,h);
                app.avg_TSP_img = I;
                imshow(I,'Parent',app.UIAxes)
                app.avg_flag = 1;
                app.med_flag = 0;
                app.flag_Pro = 0;
                app.flag_bin = 0;
            else
                IMG = app.current_TSP_img;
                I = medfilt2(IMG,Value_med);
                app.med_TSP_img = I;
                imshow(I,'Parent',app.UIAxes)
                app.avg_flag = 0;
                app.med_flag = 1;
                app.flag_Pro = 0;
                app.flag_bin = 0;
            end
            app.Lamp_2.Color = [0.39 0.83 0.07];
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            if app.flag_bin == 1
                app.current_TSP_img = app.bin_TSP_img;
                app.avg_flag = 0;
                app.med_flag = 0;
                app.flag_Pro = 0;
                app.flag_bin = 0;
                app.flag_filter = 0;
                app.flag_binary = 1;
                app.flag_noise = 0;
                app.flag_get = 0;
            end
        end

        % Button pushed function: ProcessShowButton_2
        function ProcessShowButton_2Pushed(app, event)
            siz = app.SizeofFilterElementEditField.Value;
            Method = app.MethodButtonGroup.SelectedObject;
            fun_type = app.DropDown_3.Value;
            disp(fun_type)
            strcmp(fun_type,'Extract contrast')
            if strcmp(fun_type,'Black beg. noise')
                type = 'black';
            elseif strcmp(fun_type,'White beg. noise')
                type = 'white';
            elseif strcmp(fun_type,'Extract contrast')
                type = 'white_abs';
                disp('ok')
            end
            Sec_cord_value = app.SectionCordinatesEditField_2.Value;
            Sec_cord = str2num(Sec_cord_value);
            [~,S] = size(Sec_cord);
            Cord = app.SegmentCordinatesEditField.Value;
            cordinate = str2num(Cord);
            [~,Cord_S] = size(cordinate);
            filter_siz = str2num(siz);
            [~,s] = size(filter_siz);
            if s == 0
                    filter_siz(1) = 5;
                    filter_siz(2) = 5;
            end
            flag_warn = 0;
            flag_warn2 = 0;
            if strcmp(Method.Text,'Section') && (S ~= 4)
                warndlg("Please enter the correct cordinated value","Warning");
                flag_warn = 1;
            end
            if strcmp(Method.Text,'Segment') && (Cord_S ~= 8) 
                warndlg("Please enter the correct cordinated value","Warning");
                flag_warn2 = 1;
            end

            if (s == 2) && (filter_siz(1) >= 2) && (filter_siz(2) >= 2)

                if strcmp(Method.Text,'Segment') && (Cord_S == 8)
                    [R,C] = size(app.current_TSP_img);
                    I = Quad(app.current_TSP_img,2,2,'white',cordinate);
                    h = fspecial("average",5);
                    A = imfilter(I,h);
                    B = zeros(R,C);
                    for i = 1:R
                        for j = 1:C
                            B(i,j) = A(i,j) > 0.1;
                        end
                    end
                    I0 = app.current_TSP_img - B;                    
                    for i = 1:R
                        for j= 1:R 
                            if I0(i,j) < 0
                                I0(i,j) = 0;
                            end
                        end
                    end
                end
                
                if strcmp(Method.Text,'Section') && (S == 4)
                    I = Box(app.current_TSP_img,filter_siz(1),filter_siz(2),type,Sec_cord);
                    if strcmp(type,'black')
                        img = app.current_TSP_img; 
                        I0 = I;
                        xmin = Sec_cord(1);
                        ymin = Sec_cord(2);
                        v1 = Sec_cord(4) + Sec_cord(1);
                        v2 = Sec_cord(3) + Sec_cord(2);
                        for i = xmin:v1
                            for j = ymin:v2
                                I0(i,j) = img(i,j) - I(i,j);
                                if I0(i,j) < 0
                                    I0(i,j) = 0;
                                end
                            end
                        end
                    else
                        I0 = I;
                    end
                end

                if strcmp(Method.Text,'Normal')
                    I = Box(app.current_TSP_img,filter_siz(1),filter_siz(2),type,[]);
                    if strcmp(type,'black')
                        I0 = app.current_TSP_img - I;
                        [r,c] = size(I0);
                        Z = zeros(r,c);
                        for i = 1:c
                            for j= 1:r 
                                if I0(j,i) == 1
                                    Z(j,i) = 1;
                                end
                            end
                        end
                        I0 = Z;
                    else
                        I0 = I;
                    end
                end
                if (flag_warn == 0) && (flag_warn2 == 0)
                    app.TSPPro_img = I0;
                    imshow(I0,'Parent',app.UIAxes)
                    app.flag_Pro = 1;
                end
            end
            app.Lamp.Color = [0.39 0.83 0.07];           
            
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            if app.flag_Pro == 1
                app.current_TSP_img = app.TSPPro_img;
                app.avg_flag = 0;
                app.med_flag = 0;
                app.flag_Pro = 0;
                app.flag_bin = 0;
                app.flag_filter = 0;
                app.flag_binary = 0;
                app.flag_noise = 1;
                app.flag_get = 0;
            end
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
                I = edge(app.current_TSP_img,"canny");
                app.edge_TSP_img = I;
                imshow(I,'Parent',app.UIAxes)
                app.flag_filter = 0;
                app.flag_binary = 0;
                app.flag_noise = 0;
                app.flag_get = 1;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TSP_file_count = 0;
        end

        % Button pushed function: Rotate90Button
        function Rotate90ButtonPushed(app, event)
            imgt = (app.current_crop_img)';
            [r,c] = size(imgt);
            img90 = zeros(r,c);
            for i = 1:c
                img90(:,i) = imgt(:,end-i+1);
            end
            imshow(img90,'Parent',app.UIAxes)
            app.img_90 = img90;
            app.flag_90 = 1;
            app.flag_180 = 0;
            app.flag_crop = 0;
        end

        % Button pushed function: Flip180Button
        function Flip180ButtonPushed(app, event)
            imgt = (app.current_crop_img)';
            [r,c] = size(imgt);
            img90 = zeros(r,c);
            for i = 1:c
                img90(:,i) = imgt(:,end-i+1);
            end
            imgt = (img90)';
            [r,c] = size(imgt);
            img180 = zeros(r,c);
            for i = 1:c
                img180(:,i) = imgt(:,end-i+1);
            end
            imshow(img180,'Parent',app.UIAxes)
            app.img_180 = img180;
            app.flag_90 = 0;
            app.flag_180 = 1;
            app.flag_crop = 0;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            idx = app.SelectImageEditField_2.Value;
            if idx == 0
                warndlg('Please select existing Image','Warning')
            else
                I = app.Image_Cell{idx};
                app.current_crop_img = I;
                imtool(I)
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            IMG = app.current_crop_img;
            value = app.CropdimensionEditField.Value;
            dim = str2num(value);
            [~,S] = size(dim);
            if S ~= 4
                warndlg('Wrong Dimension','Warning');
            else
                I = imcrop(IMG,dim);
                imshow(I,'Parent',app.UIAxes)
                app.crop_img = I;
                app.flag_90 = 0;
                app.flag_180 = 0;
                app.flag_crop = 1;
            end
        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
            if app.flag_90 == 1 || app.flag_180 == 1 || app.flag_crop == 1 
                app.flag_cpt_edge = 0;
                app.flag_norm_edge = 0;
                app.flag_add = 0;
                app.flag_filter = 0; 
                app.flag_binary = 0;
                app.flag_noise = 0;
                app.flag_get = 0;
                app.flag_tools = 1;
            end

            if app.flag_crop == 1
                app.current_crop_img = app.crop_img;
            elseif app.flag_90 == 1
                app.current_crop_img = app.img_90;
            elseif app.flag_180 == 1
                app.current_crop_img = app.img_180;
            end
            
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            I = app.current_crop_img;
            imshow(I,'Parent',app.UIAxes)
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.ToolsPanel.Visible = "off";
            app.xButton.Visible = "off";
        end

        % Menu selected function: ImageToolsMenu
        function ImageToolsMenuSelected(app, event)
            app.ToolsPanel.Visible = "on";
            app.xButton.Visible = "on";
        end

        % Menu selected function: Plot3DMenu
        function Plot3DMenuSelected(app, event)
            app.Tab_2.Title = 'Plot';
            app.Plot3DPanel.Visible = "on";
            app.XButton.Visible = "on";
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.Tab_2.Title = '';
            app.Plot3DPanel.Visible = "off";
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            value = app.SetcurrentprocessingTSPimageEditField.Value;
            idx = value;
            I = app.Image_Cell{idx};
            app.current_TSP_img = app.Image_Cell{idx};
            app.CurrentImageTextArea.Value = app.TextArea.Value{idx};
            imshow(I,'Parent',app.UIAxes2)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            idx = app.ImageIndexEditField.Value;
            IMG = app.Image_Cell{idx};
            [FileName,PathName] = uiputfile();
            save([PathName,FileName],'IMG');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Lamp_2.Color = [1.00 0.41 0.16];
            imshow(app.current_TSP_img,'Parent',app.UIAxes)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Lamp.Color = [1.00 0.41 0.16];
            imshow(app.current_TSP_img,'Parent',app.UIAxes)
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            imshow(app.TSPPro_img,'Parent',app.UIAxes)
        end

        % Value changing function: PrimaryedgeoptimizerSpinner
        function PrimaryedgeoptimizerSpinnerValueChanging(app, event)
            changingValue = event.Value;
            d = app.SecondaryEdgeoptimizerSpinner.Value;
            loc = changingValue;
            % idx = app.IR_imp_idx(app.imp_count);
            [r,c] = size(app.current_IR_img);
            Norm_edge = zeros(r,c);
            IMG = (app.current_IR_img);
            xpos = 1;
            for i = 1:r
                s_img = smooth(IMG(i,:));
                s_img2 = smooth(s_img);
                min_slope = 0;
                for j = loc:c-d
                    dif_Y = s_img2(j) - s_img2(j+d);
                    slope = dif_Y/d;
                    if slope < min_slope
                        min_slope = slope;
                        xpos = j;
                    end
                end
                Norm_edge(i,xpos) = 1;
            end
            app.Norm_Edge = Norm_edge;
            imshow(Norm_edge,'Parent',app.UIAxes)
        end

        % Button pushed function: ToolButton
        function ToolButtonPushed(app, event)
            imtool(app.med_TSP_img)
        end

        % Menu selected function: TSPMenu
        function TSPMenuSelected(app, event)
            app.flag_TSPMenu = 1;
            app.flag_IRMenu = 0;

            app.DropDown_2Label.Visible = "on";
            app.DropDown_2.Visible = "on";
            app.Button.Visible = "on";
            app.DirectImportCheckBox.Visible = "on";
            app.StartingIndexEditFieldLabel.Visible = "on";
            app.StartingIndexEditField.Visible = "on";
            app.NoofImagesEditFieldLabel.Visible = "on";
            app.NoofImagesEditField.Visible = "on";
            app.TSPProcessingBlockTab.Title = 'TSP Processing Block';
            app.ApplyFilterPanel.Visible = "on";
            app.ConvertToBinaryPanel.Visible = "on";
            app.Panel_3.Visible = "on";
            if app.flag_noisereduction_enable == 1
                app.ReduceNoisePanel.Visible = "on";
                app.DetectEdgePanel.Visible = "on";
            end

            app.Tab.Title = '';
            app.NormalTransitionEdgePanel.Visible = "off";
            app.DetectCompleteEdgePanel.Visible = "off";
            app.CombineEdgesPanel.Visible = "off";
            app.Panel_2.Visible = "off";
            app.XButton.Visible = "off";
        end

        % Menu selected function: IRMenu
        function IRMenuSelected(app, event)
            app.flag_IRMenu = 1;
            app.flag_TSPMenu = 0;

            app.DropDown_2Label.Visible = "off";
            app.DropDown_2.Visible = "off";
            app.Button.Visible = "off";
            app.DirectImportCheckBox.Visible = "off";
            app.StartingIndexEditFieldLabel.Visible = "off";
            app.StartingIndexEditField.Visible = "off";
            app.NoofImagesEditFieldLabel.Visible = "off";
            app.NoofImagesEditField.Visible = "off";
            app.TSPProcessingBlockTab.Title = '';
            app.ApplyFilterPanel.Visible = "off";
            app.ConvertToBinaryPanel.Visible = "off";
            app.ReduceNoisePanel.Visible = "off";
            app.DetectEdgePanel.Visible = "off";
            app.Panel_3.Visible = "off";

            app.Tab.Title = 'IR Processing Block';
            app.NormalTransitionEdgePanel.Visible = "on";
            app.DetectCompleteEdgePanel.Visible = "on";
            app.CombineEdgesPanel.Visible = "on";
            app.Panel_2.Visible = "on";
        end

        % Menu selected function: EnableMenu
        function EnableMenuSelected(app, event)
            app.flag_markermatching_enable = 1;
        end

        % Menu selected function: DisableMenu
        function DisableMenuSelected(app, event)
            app.flag_markermatching_enable = 0;
        end

        % Menu selected function: EnableMenu_2
        function EnableMenu_2Selected(app, event)
            app.flag_noisereduction_enable = 1;
            if app.flag_TSPMenu == 1
                app.ReduceNoisePanel.Visible = "on";
                app.DetectEdgePanel.Visible = "on";
            end
        end

        % Menu selected function: AdvanceOptionsMenu
        function AdvanceOptionsMenuSelected(app, event)
            app.flag_adv_opt = 1;
        end

        % Menu selected function: DisableMenu_2
        function DisableMenu_2Selected(app, event)
            app.flag_noisereduction_enable = 0;
            app.ReduceNoisePanel.Visible = "off";
            app.DetectEdgePanel.Visible = "off";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {708, 708, 708};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {708, 708};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x', 327};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Plot3DMenu = uimenu(app.Menu);
            app.Plot3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Plot3DMenuSelected, true);
            app.Plot3DMenu.Text = 'Plot 3D';
            app.ImageToolsMenu = uimenu(app.Menu);
            app.ImageToolsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageToolsMenuSelected, true);
            app.ImageToolsMenu.Text = 'Image Tools';
            app.ImageTypeMenu = uimenu(app.Menu);
            app.ImageTypeMenu.Text = 'Image Type';
            app.TSPMenu = uimenu(app.ImageTypeMenu);
            app.TSPMenu.MenuSelectedFcn = createCallbackFcn(app, @TSPMenuSelected, true);
            app.TSPMenu.Text = 'TSP';
            app.IRMenu = uimenu(app.ImageTypeMenu);
            app.IRMenu.MenuSelectedFcn = createCallbackFcn(app, @IRMenuSelected, true);
            app.IRMenu.Text = 'IR';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.MarkerMatchingMenu = uimenu(app.ToolsMenu);
            app.MarkerMatchingMenu.Text = 'Marker Matching';
            app.EnableMenu = uimenu(app.MarkerMatchingMenu);
            app.EnableMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableMenuSelected, true);
            app.EnableMenu.Text = 'Enable';
            app.DisableMenu = uimenu(app.MarkerMatchingMenu);
            app.DisableMenu.MenuSelectedFcn = createCallbackFcn(app, @DisableMenuSelected, true);
            app.DisableMenu.Text = 'Disable';
            app.AdvanceOptionsMenu = uimenu(app.MarkerMatchingMenu);
            app.AdvanceOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @AdvanceOptionsMenuSelected, true);
            app.AdvanceOptionsMenu.Text = 'Advance Options';
            app.NoiseReductionMenu = uimenu(app.ToolsMenu);
            app.NoiseReductionMenu.Text = 'Noise Reduction';
            app.EnableMenu_2 = uimenu(app.NoiseReductionMenu);
            app.EnableMenu_2.MenuSelectedFcn = createCallbackFcn(app, @EnableMenu_2Selected, true);
            app.EnableMenu_2.Text = 'Enable';
            app.DisableMenu_2 = uimenu(app.NoiseReductionMenu);
            app.DisableMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DisableMenu_2Selected, true);
            app.DisableMenu_2.Text = 'Disable';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.ImportSectionPanel = uipanel(app.LeftPanel);
            app.ImportSectionPanel.Title = 'Import Section';
            app.showButton = uibutton(app.ImportSectionPanel, 'push');
            app.showButton.ButtonPushedFcn = createCallbackFcn(app, @showButtonPushed, true);
            app.showButton.Text = 'show';
            app.ImportButton = uibutton(app.ImportSectionPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tag = 'Import';
            app.ImportButton.Text = 'Import';
            app.DropDown_2Label = uilabel(app.ImportSectionPanel);
            app.DropDown_2Label.Text = 'Select Image Type';
            app.DropDown_2 = uidropdown(app.ImportSectionPanel);
            app.DropDown_2.Items = {'Reference Image', 'Run Image', 'Dark Image'};
            app.DropDown_2.Value = 'Reference Image';
            app.Button = uibutton(app.ImportSectionPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'refresh_symbol.png');
            app.Button.Text = '';
            app.StartingIndexEditFieldLabel = uilabel(app.ImportSectionPanel);
            app.StartingIndexEditFieldLabel.Text = 'Starting Index';
            app.StartingIndexEditField = uieditfield(app.ImportSectionPanel, 'numeric');
            app.StartingIndexEditField.Value = 1;
            app.NoofImagesEditFieldLabel = uilabel(app.ImportSectionPanel);
            app.NoofImagesEditFieldLabel.Text = 'No. of Images';
            app.NoofImagesEditField = uieditfield(app.ImportSectionPanel, 'numeric');
            app.NoofImagesEditField.Value = 10;
            app.DirectImportCheckBox = uicheckbox(app.ImportSectionPanel);
            app.DirectImportCheckBox.Text = 'Direct Import';
            app.ImagePreviewCheckBox = uicheckbox(app.ImportSectionPanel);
            app.ImagePreviewCheckBox.Text = 'Image Preview';
            app.ImageFilesLabel = uilabel(app.LeftPanel);
            app.ImageFilesLabel.Text = 'Image Files';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.ShowImageButton = uibutton(app.LeftPanel, 'push');
            app.ShowImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowImageButtonPushed, true);
            app.ShowImageButton.Text = 'Show Image';
            app.ImageIndexEditFieldLabel = uilabel(app.LeftPanel);
            app.ImageIndexEditFieldLabel.Text = 'Image Index';
            app.ImageIndexEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StoreButton = uibutton(app.LeftPanel, 'push');
            app.StoreButton.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPushed, true);
            app.StoreButton.Text = 'Store';
            app.NameEditFieldLabel = uilabel(app.LeftPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.LeftPanel, 'text');
            app.Panel_4 = uipanel(app.LeftPanel);
            app.UIAxes2 = uiaxes(app.Panel_4);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.ToolsPanel = uipanel(app.GridLayout2);
            app.ToolsPanel.Title = 'Tools';
            app.ToolsPanel.Visible = 'off';
            app.xButton = uibutton(app.ToolsPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.Rotate90Button = uibutton(app.ToolsPanel, 'push');
            app.Rotate90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ButtonPushed, true);
            app.Rotate90Button.Text = 'Rotate 90';
            app.Flip180Button = uibutton(app.ToolsPanel, 'push');
            app.Flip180Button.ButtonPushedFcn = createCallbackFcn(app, @Flip180ButtonPushed, true);
            app.Flip180Button.Text = 'Flip (180) ';
            app.SelectButton = uibutton(app.ToolsPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.CropButton = uibutton(app.ToolsPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ApplyButton_4 = uibutton(app.ToolsPanel, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            app.ShowButton = uibutton(app.ToolsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.CropdimensionEditFieldLabel = uilabel(app.ToolsPanel);
            app.CropdimensionEditFieldLabel.Text = 'Crop dimension';
            app.CropdimensionEditField = uieditfield(app.ToolsPanel, 'text');
            app.SelectImageEditField_2Label = uilabel(app.ToolsPanel);
            app.SelectImageEditField_2Label.Text = 'Select Image';
            app.SelectImageEditField_2 = uieditfield(app.ToolsPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TSPProcessingBlockTab = uitab(app.TabGroup);
            app.TSPProcessingBlockTab.Title = 'TSP Processing Block';
            app.ConvertToBinaryPanel = uipanel(app.TSPProcessingBlockTab);
            app.ConvertToBinaryPanel.Title = 'Convert To Binary';
            app.InvertButton = uibutton(app.ConvertToBinaryPanel, 'state');
            app.InvertButton.ValueChangedFcn = createCallbackFcn(app, @InvertButtonValueChanged, true);
            app.InvertButton.Text = 'Invert';
            app.CutoffvalueSpinnerLabel = uilabel(app.ConvertToBinaryPanel);
            app.CutoffvalueSpinnerLabel.Text = 'Cut off value';
            app.CutoffvalueSpinner = uispinner(app.ConvertToBinaryPanel);
            app.CutoffvalueSpinner.ValueChangingFcn = createCallbackFcn(app, @CutoffvalueSpinnerValueChanging, true);
            app.ProcessShowButton = uibutton(app.ConvertToBinaryPanel, 'push');
            app.ProcessShowButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessShowButtonPushed, true);
            app.ProcessShowButton.Text = 'Process & Show';
            app.ApplyButton_2 = uibutton(app.ConvertToBinaryPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.ButtonGroup_2 = uibuttongroup(app.ConvertToBinaryPanel);
            app.NormalButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.NormalButton_2.Text = 'Normal';
            app.NormalButton_2.Value = true;
            app.SectionButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.SectionButton_2.Text = 'Section';
            app.SectionCordinatesEditFieldLabel = uilabel(app.ConvertToBinaryPanel);
            app.SectionCordinatesEditFieldLabel.Text = 'Section Cordinates';
            app.SectionCordinatesEditField = uieditfield(app.ConvertToBinaryPanel, 'text');
            app.DetectEdgePanel = uipanel(app.TSPProcessingBlockTab);
            app.DetectEdgePanel.Title = 'Detect Edge';
            app.DetectEdgePanel.Visible = 'off';
            app.GetButton = uibutton(app.DetectEdgePanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get ';
            app.ReduceNoisePanel = uipanel(app.TSPProcessingBlockTab);
            app.ReduceNoisePanel.Title = 'Reduce Noise';
            app.ReduceNoisePanel.Visible = 'off';
            app.DropDown_3 = uidropdown(app.ReduceNoisePanel);
            app.DropDown_3.Items = {'Black beg. noise', 'White beg. noise', 'Extract contrast'};
            app.DropDown_3.Value = 'Black beg. noise';
            app.ProcessShowButton_2 = uibutton(app.ReduceNoisePanel, 'push');
            app.ProcessShowButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessShowButton_2Pushed, true);
            app.ProcessShowButton_2.Text = 'Process & Show';
            app.ApplyButton_3 = uibutton(app.ReduceNoisePanel, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.SegmentCordinatesEditFieldLabel = uilabel(app.ReduceNoisePanel);
            app.SegmentCordinatesEditFieldLabel.Text = 'Segment Cordinates';
            app.SegmentCordinatesEditField = uieditfield(app.ReduceNoisePanel, 'text');
            app.MethodButtonGroup = uibuttongroup(app.ReduceNoisePanel);
            app.MethodButtonGroup.Title = 'Method';
            app.NormalButton = uiradiobutton(app.MethodButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.SegmentButton = uiradiobutton(app.MethodButtonGroup);
            app.SegmentButton.Text = 'Segment';
            app.SectionButton_3 = uiradiobutton(app.MethodButtonGroup);
            app.SectionButton_3.Text = 'Section';
            app.SizeofFilterElementEditFieldLabel = uilabel(app.ReduceNoisePanel);
            app.SizeofFilterElementEditFieldLabel.Text = 'Size of Filter Element';
            app.SizeofFilterElementEditField = uieditfield(app.ReduceNoisePanel, 'text');
            app.Lamp = uilamp(app.ReduceNoisePanel);
            app.SectionCordinatesEditField_2Label = uilabel(app.ReduceNoisePanel);
            app.SectionCordinatesEditField_2Label.Text = 'Section Cordinates';
            app.SectionCordinatesEditField_2 = uieditfield(app.ReduceNoisePanel, 'text');
            app.Button_3 = uibutton(app.ReduceNoisePanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'download.jpeg');
            app.Button_3.Text = '';
            app.DisplayButton = uibutton(app.ReduceNoisePanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display ';
            app.ApplyFilterPanel = uipanel(app.TSPProcessingBlockTab);
            app.ApplyFilterPanel.Title = 'Apply Filter';
            app.ApplyButton = uibutton(app.ApplyFilterPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.MedianEditFieldLabel = uilabel(app.ApplyFilterPanel);
            app.MedianEditFieldLabel.Text = 'Median';
            app.MedianEditField = uieditfield(app.ApplyFilterPanel, 'text');
            app.AverageEditFieldLabel = uilabel(app.ApplyFilterPanel);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.ApplyFilterPanel, 'text');
            app.AverageEditField.InputType = 'digits';
            app.ButtonGroup = uibuttongroup(app.ApplyFilterPanel);
            app.MedianButton = uiradiobutton(app.ButtonGroup);
            app.MedianButton.Text = 'Median';
            app.MedianButton.Value = true;
            app.AverageButton = uiradiobutton(app.ButtonGroup);
            app.AverageButton.Text = 'Average';
            app.ProcessShowButton_3 = uibutton(app.ApplyFilterPanel, 'push');
            app.ProcessShowButton_3.ButtonPushedFcn = createCallbackFcn(app, @ProcessShowButton_3Pushed, true);
            app.ProcessShowButton_3.Text = 'Process & Show';
            app.Lamp_2 = uilamp(app.ApplyFilterPanel);
            app.Button_2 = uibutton(app.ApplyFilterPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'download.jpeg');
            app.Button_2.Text = '';
            app.ToolButton = uibutton(app.ApplyFilterPanel, 'push');
            app.ToolButton.ButtonPushedFcn = createCallbackFcn(app, @ToolButtonPushed, true);
            app.ToolButton.Text = 'Tool';
            app.Panel_3 = uipanel(app.TSPProcessingBlockTab);
            app.SetcurrentprocessingTSPimageEditFieldLabel = uilabel(app.Panel_3);
            app.SetcurrentprocessingTSPimageEditFieldLabel.Text = 'Set current processing TSP image';
            app.SetcurrentprocessingTSPimageEditField = uieditfield(app.Panel_3, 'numeric');
            app.SetcurrentprocessingTSPimageEditField.Limits = [1 Inf];
            app.SetcurrentprocessingTSPimageEditField.Value = 1;
            app.SelectLabel_2 = uilabel(app.Panel_3);
            app.SelectLabel_2.Text = 'Select';
            app.SetButton_2 = uibutton(app.Panel_3, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.CurrentImageTextAreaLabel = uilabel(app.Panel_3);
            app.CurrentImageTextAreaLabel.Text = 'Current Image';
            app.CurrentImageTextArea = uitextarea(app.Panel_3);
            app.Tab = uitab(app.TabGroup);
            app.NormalTransitionEdgePanel = uipanel(app.Tab);
            app.NormalTransitionEdgePanel.Title = 'Normal Transition Edge';
            app.NormalTransitionEdgePanel.Visible = 'off';
            app.PrimaryedgeoptimizerLabel = uilabel(app.NormalTransitionEdgePanel);
            app.PrimaryedgeoptimizerLabel.Text = 'Primary edge optimizer';
            app.PrimaryedgeoptimizerSpinner = uispinner(app.NormalTransitionEdgePanel);
            app.PrimaryedgeoptimizerSpinner.Step = 20;
            app.PrimaryedgeoptimizerSpinner.ValueChangingFcn = createCallbackFcn(app, @PrimaryedgeoptimizerSpinnerValueChanging, true);
            app.PrimaryedgeoptimizerSpinner.Limits = [1 Inf];
            app.PrimaryedgeoptimizerSpinner.Value = 1;
            app.SecondaryEdgeoptimizerSpinnerLabel = uilabel(app.NormalTransitionEdgePanel);
            app.SecondaryEdgeoptimizerSpinnerLabel.Text = 'Secondary Edge  optimizer';
            app.SecondaryEdgeoptimizerSpinner = uispinner(app.NormalTransitionEdgePanel);
            app.SecondaryEdgeoptimizerSpinner.Step = 5;
            app.SecondaryEdgeoptimizerSpinner.ValueChangingFcn = createCallbackFcn(app, @SecondaryEdgeoptimizerSpinnerValueChanging, true);
            app.SecondaryEdgeoptimizerSpinner.Limits = [1 Inf];
            app.SecondaryEdgeoptimizerSpinner.Value = 1;
            app.ProcessApplyButton = uibutton(app.NormalTransitionEdgePanel, 'push');
            app.ProcessApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessApplyButtonPushed, true);
            app.ProcessApplyButton.Text = 'Process & Apply';
            app.DetectCompleteEdgePanel = uipanel(app.Tab);
            app.DetectCompleteEdgePanel.Title = 'Detect Complete Edge';
            app.DetectCompleteEdgePanel.Visible = 'off';
            app.GetEdgeButton_2 = uibutton(app.DetectCompleteEdgePanel, 'push');
            app.GetEdgeButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetEdgeButton_2Pushed, true);
            app.GetEdgeButton_2.Text = 'Get Edge';
            app.CombineEdgesPanel = uipanel(app.Tab);
            app.CombineEdgesPanel.Title = 'Combine Edges';
            app.CombineEdgesPanel.Visible = 'off';
            app.SelectImageIndicesEditFieldLabel = uilabel(app.CombineEdgesPanel);
            app.SelectImageIndicesEditFieldLabel.Text = 'Select Image Indices';
            app.SelectImageIndicesEditField = uieditfield(app.CombineEdgesPanel, 'text');
            app.SelectImageIndicesEditField.ValueChangedFcn = createCallbackFcn(app, @SelectImageIndicesEditFieldValueChanged, true);
            app.AddButton = uibutton(app.CombineEdgesPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Visible = 'off';
            app.SetcurrentprocessingIRimageEditFieldLabel = uilabel(app.Panel_2);
            app.SetcurrentprocessingIRimageEditFieldLabel.Text = 'Set current processing IR image';
            app.SetcurrentprocessingIRimageEditField = uieditfield(app.Panel_2, 'numeric');
            app.SetcurrentprocessingIRimageEditField.Limits = [1 Inf];
            app.SetcurrentprocessingIRimageEditField.Value = 1;
            app.SelectLabel = uilabel(app.Panel_2);
            app.SelectLabel.Text = 'Select';
            app.SetButton = uibutton(app.Panel_2, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Tab_2 = uitab(app.TabGroup);
            app.Plot3DPanel = uipanel(app.Tab_2);
            app.Plot3DPanel.Title = 'Plot 3D';
            app.Plot3DPanel.Visible = 'off';
            app.LeadingEdgeofSectionEditFieldLabel = uilabel(app.Plot3DPanel);
            app.LeadingEdgeofSectionEditFieldLabel.Text = 'Leading Edge of Section';
            app.LeadingEdgeofSectionEditField = uieditfield(app.Plot3DPanel, 'numeric');
            app.TrailingEdgeofsectionEditFieldLabel = uilabel(app.Plot3DPanel);
            app.TrailingEdgeofsectionEditFieldLabel.Text = 'Trailing Edge of section';
            app.TrailingEdgeofsectionEditField = uieditfield(app.Plot3DPanel, 'numeric');
            app.AspectRatioAREditFieldLabel = uilabel(app.Plot3DPanel);
            app.AspectRatioAREditFieldLabel.Text = 'Aspect Ratio (AR)';
            app.AspectRatioAREditField = uieditfield(app.Plot3DPanel, 'numeric');
            app.PlotButton = uibutton(app.Plot3DPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SelectImageEditFieldLabel = uilabel(app.Plot3DPanel);
            app.SelectImageEditFieldLabel.Text = 'Select Image';
            app.SelectImageEditField = uieditfield(app.Plot3DPanel, 'numeric');
            app.SelectColorScaleDropDownLabel = uilabel(app.Plot3DPanel);
            app.SelectColorScaleDropDownLabel.Text = 'Select Color Scale ';
            app.SelectColorScaleDropDown = uidropdown(app.Plot3DPanel);
            app.SelectColorScaleDropDown.Items = {'jet', 'turbo', 'parula', 'hot', 'autumn'};
            app.SelectColorScaleDropDown.Value = 'jet';
            app.XButton = uibutton(app.Plot3DPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Visible = 'off';
            app.XButton.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end